classdef BasicApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        InputTab                     matlab.ui.container.Tab
        GetconfigfileButton          matlab.ui.control.Button
        GetTLEfileButton             matlab.ui.control.Button
        ConfigfilepathTextAreaLabel  matlab.ui.control.Label
        ConfigfilepathTextArea       matlab.ui.control.TextArea
        TLEfilepathTextAreaLabel     matlab.ui.control.Label
        TLEfilepathTextArea          matlab.ui.control.TextArea
        RunsimulationButton          matlab.ui.control.Button
        ResultboxTextAreaLabel       matlab.ui.control.Label
        ResultboxTextArea            matlab.ui.control.TextArea
        ofdownlinksSpinnerLabel      matlab.ui.control.Label
        ofdownlinksSpinner           matlab.ui.control.Spinner
        OuputTab                     matlab.ui.container.Tab
        TabGroup2                    matlab.ui.container.TabGroup
        Downlink1Tab                 matlab.ui.container.Tab
        UITable                      matlab.ui.control.Table
        SelectionDropDownLabel       matlab.ui.control.Label
        SelectionDropDown            matlab.ui.control.DropDown
        RegenerateButton             matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        TotalcosttodownlinkLabel     matlab.ui.control.Label
        TotalcosttodownlinkTextArea  matlab.ui.control.TextArea
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function CreatescenarioButtonPushed(app, event)
            apogee = app.ApogeeAltitudeEditField.Value;
            perigee = app.PerigeeAltitudeEditField.Value;
            inclination = app.InclinationEditField.Value;
            
            %% Inital Setup

            % Connect to STK
            ap = actxserver('STK12.application');
            root = ap.Personality2;

            % Scenario Setup
            scenario = root.Children.New('eScenario','MATLAB_AurisOrbit');

            %scenario.SetTimePeriod('1 Mar 2023 00:00:00.000','1 Mar 2023 06:00:00.000'); %Scenario time period
            scenario.SetTimePeriod('1 Mar 2023 00:00:00.000','1 Mar 2024 00:00:00.000'); %Scenario time period
            scenario.StartTime = '1 Mar 2023 00:00:00.000'; %Scenario start time

            %scenario.StopTime = '1 Mar 2023 06:00:00.000'; %Scenario end time
            scenario.StopTime = '1 Mar 2024 00:00:00.000'; %Scenario end time
            root.ExecuteCommand('Animate * Reset');
            root.UnitPreferences.Item('DateFormat').SetCurrentUnit('EpSec'); %Sets time unit to epsec

            %% Satellite Setup
            satellite = scenario.Children.New('eSatellite','Auris');  %creates the auris satellite object
            model = satellite.VO.Model; %shortcut to modify satellite model properties

            % Define model file location (should be a .dae file with a .anc file of the same name in the same folder)
            model.ModelData.Filename = 'G:\Shared drives\MTU Aerospace\(2) Auris\(2) Mission Level\(1) Mission Operations [MOP]\(3) Illumination Analysis\auris9u.dae';
            keplerian = satellite.Propagator.InitialState.Representation.ConvertTo('eOrbitStateClassical'); % Use the Classical Element interface
            keplerian.SizeShapeType = 'eSizeShapeAltitude';  % Changes from Ecc/Inc to Perigee/Apogee Altitude
            keplerian.LocationType = 'eLocationTrueAnomaly'; % Makes sure True Anomaly is being used
            keplerian.Orientation.AscNodeType = 'eAscNodeLAN'; % Use LAN instead of RAAN for data entry

            % Assign the perigee and apogee altitude values:
            keplerian.SizeShape.PerigeeAltitude = perigee;      % km
            keplerian.SizeShape.ApogeeAltitude = apogee;        % km

            % Assign the other desired orbital parameters:
            keplerian.Orientation.Inclination = inclination;    % deg
            keplerian.Orientation.ArgOfPerigee = 0;             % deg
            keplerian.Orientation.AscNode.Value =0;             % deg
            keplerian.Location.Value = 0;                       % deg

            % Apply the changes made to the satellite's state and propagate:
            satellite.Propagator.InitialState.Representation.Assign(keplerian);
            satellite.Propagator.Propagate;            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputTab = uitab(app.TabGroup);
            app.InputTab.Title = 'Input';
            app.GetconfigfileButton = uibutton(app.InputTab, 'push');
            app.GetconfigfileButton.Text = 'Get config file';
            app.GetTLEfileButton = uibutton(app.InputTab, 'push');
            app.GetTLEfileButton.Text = 'Get TLE file';
            app.ConfigfilepathTextAreaLabel = uilabel(app.InputTab);
            app.ConfigfilepathTextAreaLabel.Text = 'Config file path';
            app.ConfigfilepathTextArea = uitextarea(app.InputTab);
            app.TLEfilepathTextAreaLabel = uilabel(app.InputTab);
            app.TLEfilepathTextAreaLabel.Text = 'TLE file path';
            app.TLEfilepathTextArea = uitextarea(app.InputTab);
            app.RunsimulationButton = uibutton(app.InputTab, 'push');
            app.RunsimulationButton.Text = 'Run simulation';
            app.ResultboxTextAreaLabel = uilabel(app.InputTab);
            app.ResultboxTextAreaLabel.Text = 'Result box';
            app.ResultboxTextArea = uitextarea(app.InputTab);
            app.ofdownlinksSpinnerLabel = uilabel(app.InputTab);
            app.ofdownlinksSpinnerLabel.Text = '# of downlinks';
            app.ofdownlinksSpinner = uispinner(app.InputTab);
            app.ofdownlinksSpinner.Limits = [1 10];
            app.ofdownlinksSpinner.Value = 1;
            app.OuputTab = uitab(app.TabGroup);
            app.OuputTab.Title = 'Ouput';
            app.TabGroup2 = uitabgroup(app.OuputTab);
            app.Downlink1Tab = uitab(app.TabGroup2);
            app.Downlink1Tab.Title = 'Downlink 1';
            app.UITable = uitable(app.Downlink1Tab);
            app.UITable.ColumnName = {'Interval #'; 'Start Time'; 'End Time'; 'Ground Station'; '% capacity'; '$ to downlink'};
            app.UITable.RowName = {};
            app.SelectionDropDownLabel = uilabel(app.Downlink1Tab);
            app.SelectionDropDownLabel.Text = 'Selection';
            app.SelectionDropDown = uidropdown(app.Downlink1Tab);
            app.SelectionDropDown.Items = {'NA'};
            app.SelectionDropDown.Value = 'NA';
            app.RegenerateButton = uibutton(app.Downlink1Tab, 'push');
            app.RegenerateButton.Text = 'Regenerate';
            app.UIAxes = uiaxes(app.OuputTab);
            title(app.UIAxes, 'Historical Graph')
            xlabel(app.UIAxes, 'Time Since Simulation Start')
            ylabel(app.UIAxes, 'Storage in Satellite [MB]')
            app.TotalcosttodownlinkLabel = uilabel(app.OuputTab);
            app.TotalcosttodownlinkLabel.Text = {'Total cost '; 'to downlink'};
            app.TotalcosttodownlinkTextArea = uitextarea(app.OuputTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BasicApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end